classdef MissionPlotter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ClosefiguresButton              matlab.ui.control.Button
        PlotsCheckBox                   matlab.ui.control.CheckBox
        ExporttoCSVCheckBox             matlab.ui.control.CheckBox
        MissionStatsLabel               matlab.ui.control.Label
        RegressionslopesvsdiveNumPanel  matlab.ui.container.Panel
        rollIEditField                  matlab.ui.control.NumericEditField
        rollIEditFieldLabel             matlab.ui.control.Label
        pitchIEditField                 matlab.ui.control.NumericEditField
        pitchIEditFieldLabel            matlab.ui.control.Label
        vbdIEditField                   matlab.ui.control.NumericEditField
        vbdIEditFieldLabel              matlab.ui.control.Label
        vbdrateEditField                matlab.ui.control.NumericEditField
        vbdrateEditFieldLabel           matlab.ui.control.Label
        rollrateEditField               matlab.ui.control.NumericEditField
        rollrateEditFieldLabel          matlab.ui.control.Label
        pitchrateEditField              matlab.ui.control.NumericEditField
        pitchrateEditFieldLabel         matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        ConfigurationPanel              matlab.ui.container.Panel
        rollMintimeEditField            matlab.ui.control.NumericEditField
        rollMintimeEditFieldLabel       matlab.ui.control.Label
        pitchMintimeEditField           matlab.ui.control.NumericEditField
        pitchMintimeEditFieldLabel      matlab.ui.control.Label
        vbdMintimeEditField             matlab.ui.control.NumericEditField
        vbdMintimeEditFieldLabel        matlab.ui.control.Label
        MaxDiveEditField                matlab.ui.control.EditField
        MaxDiveEditFieldLabel           matlab.ui.control.Label
        MinDiveEditField                matlab.ui.control.EditField
        MinDiveEditFieldLabel           matlab.ui.control.Label
        FormatButtonGroup               matlab.ui.container.ButtonGroup
        OldButton                       matlab.ui.control.RadioButton
        NewButton                       matlab.ui.control.RadioButton
        GOButton                        matlab.ui.control.Button
        SeaGliderDropDown               matlab.ui.control.DropDown
        SeaGliderDropDownLabel          matlab.ui.control.Label
        BrowseButton                    matlab.ui.control.Button
        DirectoryEditField              matlab.ui.control.EditField
        DirectoryEditFieldLabel         matlab.ui.control.Label
        MISSIONLOGPLOTTERLabel          matlab.ui.control.Label
    end

    
    properties (Access = public)
        gliderDir % directory with log files
    end
    
    methods (Access = public)
        
        function idList = get_gliders(app,fileList)
            idList = [""];    
            i = 1;
            for n = 1:length(fileList)
                id = char(fileList(n));
                id = string(id(2:4));
                if ~ismember(id,idList)
                    idList(i) = id;
                    i=i+1;
                end
            end
            idList = sort(idList);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            app.gliderDir = uigetdir;
            app.DirectoryEditField.Value = app.gliderDir;

            fileList = dir(fullfile( app.DirectoryEditField.Value, strcat("p*.log")));
            fileList = {fileList.name};
            fileList = convertCharsToStrings(fileList);
            gliderList = app.get_gliders(fileList);
%             disp(gliderList);
            app.SeaGliderDropDown.Items = gliderList;


        end

        % Button pushed function: GOButton
        function GOButtonPushed(app, event)
            gliderID = app.SeaGliderDropDown.Value;
            dirPath = app.DirectoryEditField.Value;
            minDiveNum = str2num(convertCharsToStrings(app.MinDiveEditField.Value));
            maxDiveNum = str2num(convertCharsToStrings(app.MaxDiveEditField.Value));
            logFormat = app.NewButton.Value;
            minTime.vbd = app.vbdMintimeEditField.Value;
            minTime.pitch = app.pitchMintimeEditField.Value;
            minTime.roll = app.rollMintimeEditField.Value;
            ecsv = app.ExporttoCSVCheckBox.Value; % false/true
            plots = app.PlotsCheckBox.Value; % true/false                

            try
                [statsTable,regressionValues] = process_logs(gliderID, dirPath, minDiveNum, maxDiveNum, logFormat, minTime,ecsv,plots);
    
                % assign values to table and regression slopes
                app.UITable.Data = statsTable;
                
                if plots == true
                    app.vbdrateEditField.Value = regressionValues.vbdRate;   
                    app.rollrateEditField.Value = regressionValues.rollRate; 
                    app.pitchrateEditField.Value = regressionValues.pitchRate;
                    app.vbdIEditField.Value = regressionValues.vbd_i;
                    app.rollIEditField.Value = regressionValues.roll_i;
                    app.pitchIEditField.Value = regressionValues.pitch_i;    
                end
            catch
                errordlg("Make sure there are log files for the specified dive ranage");
            end
            
        end

        % Button pushed function: ClosefiguresButton
        function ClosefiguresButtonPushed(app, event)
            try
                close("Rates_depth");
                close("Rates_histogram");
                close("Rates_diveNum");
                close("Errors");
            catch
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MISSIONLOGPLOTTERLabel = uilabel(app.UIFigure);
            app.MISSIONLOGPLOTTERLabel.Text = 'MISSION LOG PLOTTER';
            app.DirectoryEditFieldLabel = uilabel(app.UIFigure);
            app.DirectoryEditFieldLabel.Text = 'Directory';
            app.DirectoryEditField = uieditfield(app.UIFigure, 'text');
            app.BrowseButton = uibutton(app.UIFigure, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.SeaGliderDropDownLabel = uilabel(app.UIFigure);
            app.SeaGliderDropDownLabel.Text = 'SeaGlider';
            app.SeaGliderDropDown = uidropdown(app.UIFigure);
            app.SeaGliderDropDown.Items = {'sgnnn', ''};
            app.SeaGliderDropDown.Value = 'sgnnn';
            app.GOButton = uibutton(app.UIFigure, 'push');
            app.GOButton.ButtonPushedFcn = createCallbackFcn(app, @GOButtonPushed, true);
            app.GOButton.Text = 'GO';
            app.ConfigurationPanel = uipanel(app.UIFigure);
            app.ConfigurationPanel.Title = 'Configuration';
            app.FormatButtonGroup = uibuttongroup(app.ConfigurationPanel);
            app.FormatButtonGroup.Title = 'Format';
            app.NewButton = uiradiobutton(app.FormatButtonGroup);
            app.NewButton.Text = 'New';
            app.NewButton.Value = true;
            app.OldButton = uiradiobutton(app.FormatButtonGroup);
            app.OldButton.Text = 'Old';
            app.MinDiveEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.MinDiveEditFieldLabel.Text = 'Min Dive';
            app.MinDiveEditField = uieditfield(app.ConfigurationPanel, 'text');
            app.MinDiveEditField.Value = '1';
            app.MaxDiveEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.MaxDiveEditFieldLabel.Text = 'Max Dive';
            app.MaxDiveEditField = uieditfield(app.ConfigurationPanel, 'text');
            app.MaxDiveEditField.Tooltip = {'plot dives from Min to Max'};
            app.MaxDiveEditField.Value = '2000';
            app.vbdMintimeEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.vbdMintimeEditFieldLabel.Text = 'vbd Min time';
            app.vbdMintimeEditField = uieditfield(app.ConfigurationPanel, 'numeric');
            app.vbdMintimeEditField.Tooltip = {'Consider values with an active time geater than this value only'};
            app.vbdMintimeEditField.Value = 0.01;
            app.pitchMintimeEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.pitchMintimeEditFieldLabel.Text = 'pitch Min time';
            app.pitchMintimeEditField = uieditfield(app.ConfigurationPanel, 'numeric');
            app.pitchMintimeEditField.Tooltip = {'Consider values with an active time geater than this value only'};
            app.pitchMintimeEditField.Value = 0.01;
            app.rollMintimeEditFieldLabel = uilabel(app.ConfigurationPanel);
            app.rollMintimeEditFieldLabel.Text = 'roll Min time';
            app.rollMintimeEditField = uieditfield(app.ConfigurationPanel, 'numeric');
            app.rollMintimeEditField.Tooltip = {'Consider values with an active time geater than this value only'};
            app.rollMintimeEditField.Value = 0.01;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'MIN RATE'; 'MEAN RATE'; 'MAX RATE'; 'MIN CURRENT'; 'MEAN CURRENT'; 'MAX CURRENT'; 'RETRIES'; 'ERRORS'};
            app.UITable.RowName = {'VBD'; 'PITCH'; 'ROLL'};
            app.RegressionslopesvsdiveNumPanel = uipanel(app.UIFigure);
            app.RegressionslopesvsdiveNumPanel.Title = 'Regression slopes - vs diveNum';
            app.pitchrateEditFieldLabel = uilabel(app.RegressionslopesvsdiveNumPanel);
            app.pitchrateEditFieldLabel.Enable = 'off';
            app.pitchrateEditFieldLabel.Text = 'pitch rate';
            app.pitchrateEditField = uieditfield(app.RegressionslopesvsdiveNumPanel, 'numeric');
            app.pitchrateEditField.ValueDisplayFormat = '%.4f';
            app.pitchrateEditField.Editable = 'off';
            app.pitchrateEditField.Enable = 'off';
            app.rollrateEditFieldLabel = uilabel(app.RegressionslopesvsdiveNumPanel);
            app.rollrateEditFieldLabel.Enable = 'off';
            app.rollrateEditFieldLabel.Text = 'roll rate';
            app.rollrateEditField = uieditfield(app.RegressionslopesvsdiveNumPanel, 'numeric');
            app.rollrateEditField.ValueDisplayFormat = '%.4f';
            app.rollrateEditField.Editable = 'off';
            app.rollrateEditField.Enable = 'off';
            app.vbdrateEditFieldLabel = uilabel(app.RegressionslopesvsdiveNumPanel);
            app.vbdrateEditFieldLabel.Enable = 'off';
            app.vbdrateEditFieldLabel.Text = 'vbd rate';
            app.vbdrateEditField = uieditfield(app.RegressionslopesvsdiveNumPanel, 'numeric');
            app.vbdrateEditField.ValueDisplayFormat = '%.4f';
            app.vbdrateEditField.Editable = 'off';
            app.vbdrateEditField.Enable = 'off';
            app.vbdIEditFieldLabel = uilabel(app.RegressionslopesvsdiveNumPanel);
            app.vbdIEditFieldLabel.Enable = 'off';
            app.vbdIEditFieldLabel.Text = 'vbd I';
            app.vbdIEditField = uieditfield(app.RegressionslopesvsdiveNumPanel, 'numeric');
            app.vbdIEditField.ValueDisplayFormat = '%.4f';
            app.vbdIEditField.Editable = 'off';
            app.vbdIEditField.Enable = 'off';
            app.pitchIEditFieldLabel = uilabel(app.RegressionslopesvsdiveNumPanel);
            app.pitchIEditFieldLabel.Enable = 'off';
            app.pitchIEditFieldLabel.Text = 'pitch I';
            app.pitchIEditField = uieditfield(app.RegressionslopesvsdiveNumPanel, 'numeric');
            app.pitchIEditField.ValueDisplayFormat = '%.4f';
            app.pitchIEditField.Editable = 'off';
            app.pitchIEditField.Enable = 'off';
            app.rollIEditFieldLabel = uilabel(app.RegressionslopesvsdiveNumPanel);
            app.rollIEditFieldLabel.Enable = 'off';
            app.rollIEditFieldLabel.Text = 'roll I';
            app.rollIEditField = uieditfield(app.RegressionslopesvsdiveNumPanel, 'numeric');
            app.rollIEditField.ValueDisplayFormat = '%.4f';
            app.rollIEditField.Editable = 'off';
            app.rollIEditField.Enable = 'off';
            app.MissionStatsLabel = uilabel(app.UIFigure);
            app.MissionStatsLabel.Text = 'Mission Stats';
            app.ExporttoCSVCheckBox = uicheckbox(app.UIFigure);
            app.ExporttoCSVCheckBox.Text = 'Export to CSV';
            app.PlotsCheckBox = uicheckbox(app.UIFigure);
            app.PlotsCheckBox.Text = 'Plots';
            app.PlotsCheckBox.Value = true;
            app.ClosefiguresButton = uibutton(app.UIFigure, 'push');
            app.ClosefiguresButton.ButtonPushedFcn = createCallbackFcn(app, @ClosefiguresButtonPushed, true);
            app.ClosefiguresButton.Text = 'Close figures';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MissionPlotter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end